classdef InterfaceSimulink < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RafrachirButton             matlab.ui.control.Button
        MassemesuregEditField       matlab.ui.control.NumericEditField
        MassemesuregEditFieldLabel  matlab.ui.control.Label
        EntreMassegEditField        matlab.ui.control.NumericEditField
        EntreMasseKgLabel           matlab.ui.control.Label
        ArrtersimulationButton      matlab.ui.control.Button
        DmarrersimulationButton     matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        PlotTimer                   % Minuteur pour rafraîchir les données
        LiveLine                    % Objet graphique représentant la courbe
    end
    
    properties (Access = public)
        Property3 % Description
    end
    % Callbacks that handle component events
    methods (Access = private)
        % Fonction personnalisée exécutée par le minuteur (Timer)
        function updatePlot(app)
            % Vérifie si la simulation tourne toujours
            status = get_param('Simulation_balanceversionajour2024_avec_statespace','SimulationStatus');
            
            if strcmp(status, 'running')
                try
                    % 1. Lire le temps actuel de la simulation
                    t = get_param('Simulation_balanceversionajour2024_avec_statespace', 'SimulationTime');
                    
                    % 2. Lire la valeur de la masse
                    rto = get_param('Simulation_balanceversionajour2024_avec_statespace/Mesure de masse (g)','RuntimeObject');
                    
                    if ~isempty(rto)
                        valeur_lue = double(rto.InputPort(1).Data);
                        
                        % 3. Mettre à jour les données de la ligne graphique
                        app.LiveLine.XData = [app.LiveLine.XData, t];
                        app.LiveLine.YData = [app.LiveLine.YData, valeur_lue];
                        
                        % 4. Mettre à jour la case de texte simultanément
                        app.MassemesuregEditField.Value = valeur_lue;
                    end
                catch
                    % Ignore les petites erreurs si les données ne sont pas prêtes
                end
            elseif strcmp(status, 'stopped')
                % Si la simulation se termine d'elle-même, on arrête le minuteur
                if isvalid(app.PlotTimer) && strcmp(app.PlotTimer.Running, 'on')
                    stop(app.PlotTimer);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DmarrersimulationButton
        function DmarrersimulationButtonPushed(app, event)
            % 1. Réinitialiser le graphique avant de commencer
            app.LiveLine.XData = [];
            app.LiveLine.YData = [];
            
            % 2. Démarrer la simulation
            set_param('Simulation_balanceversionajour2024_avec_statespace', 'SimulationCommand', 'start');
            
            % 3. Configurer et démarrer le minuteur (mise à jour toutes les 0.1s)
            if isempty(app.PlotTimer) || ~isvalid(app.PlotTimer)
                app.PlotTimer = timer('ExecutionMode', 'fixedRate', ...
                                      'Period', 0.1, ...
                                      'TimerFcn', @(~,~)updatePlot(app));
            end
            start(app.PlotTimer);
        end

        % Button pushed function: ArrtersimulationButton
        function ArrtersimulationButtonPushed(app, event)
            % 1. Arrêter la simulation
            set_param('Simulation_balanceversionajour2024_avec_statespace', 'SimulationCommand', 'stop');
            
            % 2. Arrêter le minuteur
            if ~isempty(app.PlotTimer) && isvalid(app.PlotTimer)
                stop(app.PlotTimer);
            end
        end

        % Value changed function: EntreMassegEditField
        function EntreMassegEditFieldValueChanged(app, event)
            nouvelle_masse = app.EntreMassegEditField.Value;
            valeur_texte = num2str(nouvelle_masse);
            set_param('Simulation_balanceversionajour2024_avec_statespace/Masse (g)', 'value', valeur_texte);
        end

        % Value changed function: MassemesuregEditField
        function MassemesuregEditFieldValueChanged(app, event)
           % Cette section n'est presque plus nécessaire car updatePlot s'en occupe, 
            % mais elle est conservée pour respecter ta structure.
            value = app.MassemesuregEditField.Value;
            status = get_param('Simulation_balanceversionajour2024_avec_statespace','SimulationStatus');
            if strcmp(status, 'running')
                rto =  get_param('Simulation_balanceversionajour2024_avec_statespace/Mesure de masse (g)','RuntimeObject');
                if ~isempty(rto)
                    valeur_lue = rto.InputPort(1).Data;
                    app.MassemesuregEditField.Value = double(valeur_lue);
                end
            else
                uialert(app.UIFigure, 'La simulation doit être en marche pour lire la masse.', 'Erreur');
            end
        end

        % Button pushed function: RafrachirButton
        function RafrachirButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Masse mesuré (g) selon le temps (s)')
            xlabel(app.UIAxes, 'Temps (s)')
            ylabel(app.UIAxes, 'Masse mesuré (g) ')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5];
            app.UIAxes.XTickLabel = {'0'; ''; ''; ''; ''; ''; ''; ''; ''; ''; '1'; ''; ''; ''; ''; ''; ''; ''; ''; ''; '2'; ''; ''; ''; '0.2'; ''; ''; ''; ''; ''; '3'; ''; ''; ''; ''; ''; ''; ''; ''; ''; '4'; ''; ''; ''; ''; ''; ''; ''; ''; ''; '5'};
            app.UIAxes.YTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.UIAxes.YTickLabel = {'0'; '1'; '0'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'; '61'; '62'; '63'; '64'; '65'; '66'; '67'; '68'; '69'; '70'; '71'; '72'; '73'; '74'; '75'; '76'; '77'; '78'; '79'; '80'; '81'; '82'; '83'; '84'; '85'; '86'; '87'; '88'; '89'; '90'; '91'; '92'; '93'; '94'; '95'; '96'; '97'; '98'; '99'; '100'};
            app.DmarrersimulationButton = uibutton(app.UIFigure, 'push');
            app.DmarrersimulationButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrersimulationButtonPushed, true);
            app.DmarrersimulationButton.Text = 'Démarrer simulation';
            app.ArrtersimulationButton = uibutton(app.UIFigure, 'push');
            app.ArrtersimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ArrtersimulationButtonPushed, true);
            app.ArrtersimulationButton.Text = 'Arrêter simulation';
            app.EntreMasseKgLabel = uilabel(app.UIFigure);
            app.EntreMasseKgLabel.Text = 'Entrée Masse (g) ';
            app.EntreMassegEditField = uieditfield(app.UIFigure, 'numeric');
            app.EntreMassegEditField.ValueChangedFcn = createCallbackFcn(app, @EntreMassegEditFieldValueChanged, true);
            app.MassemesuregEditFieldLabel = uilabel(app.UIFigure);
            app.MassemesuregEditFieldLabel.Text = 'Masse mesurée (g) ';
            app.MassemesuregEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassemesuregEditField.ValueChangedFcn = createCallbackFcn(app, @MassemesuregEditFieldValueChanged, true);
            app.MassemesuregEditField.Editable = 'off';
            app.RafrachirButton = uibutton(app.UIFigure, 'push');
            app.RafrachirButton.ButtonPushedFcn = createCallbackFcn(app, @RafrachirButtonPushed, true);
            app.RafrachirButton.Text = 'Rafraîchir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceSimulink

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end